classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DemodulateButton                matlab.ui.control.Button
        NoiseSlider                     matlab.ui.control.Slider
        NoiseSliderLabel                matlab.ui.control.Label
        GeneratePSDandTimedomainButton  matlab.ui.control.Button
        OUTPUTLabel                     matlab.ui.control.Label
        ModulateButton                  matlab.ui.control.Button
        ModulationButtonGroup           matlab.ui.container.ButtonGroup
        FSK64Button                     matlab.ui.control.RadioButton
        QAM64Button                     matlab.ui.control.RadioButton
        QAM16Button                     matlab.ui.control.RadioButton
        FSK16Button                     matlab.ui.control.RadioButton
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        INPUTLabel                      matlab.ui.control.Label
        GenerateCodeButton              matlab.ui.control.Button
        CODESButtonGroup                matlab.ui.container.ButtonGroup
        BCH6351codeButton               matlab.ui.control.RadioButton
        BCH12764codeButton              matlab.ui.control.RadioButton
        HammingCode74Button             matlab.ui.control.RadioButton
        GenerateBitsButton              matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        VoiceButton                     matlab.ui.control.RadioButton
        ImageFileButton                 matlab.ui.control.RadioButton
        TextFileButton                  matlab.ui.control.RadioButton
        RandomBitsButton                matlab.ui.control.RadioButton
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        recObj; % Description
        randomBits;
        Hamming;
        BCH127_64;
        BCH63_51;
        gfstring;
        b1;
        b2;
        b3;
        mat1;
        mat2;
        TimeDomainAxes;
        NoisySignal;
        neededBits;
        remainingBits;
        padding;
        mat3;
        mat4;
    end
    
    methods (Access = private)
        
        function randomBits = generateRandomBits(~, numBits)
            randomBits = randi([0, 1], 1, numBits);
        end


        function textBits = textToBits(~, filename)
            textData = fileread(filename);
            
            asciiValues = double(textData);
            
            textBits = dec2bin(asciiValues, 8)';
            textBits = textBits(:)' - '0';
        end

        
        function imageBits = imageToBits(~, filename)
            img = imread(filename);
            
            if size(img, 3) == 3
                img = rgb2gray(img);
            end
            
            img = double(img) / 255;
            
            imageBits = reshape(dec2bin(img, 8).' - '0', 1, []);
        end

        
        function audioBits = audioToBits(~, f)
            audioBits = reshape(dec2bin(round(f * (2^15)), 16).' - '0', 1, []);
        end


        function Hamming = Hamming1(~, arr)
            % Input data (array of numbers)
            inputArray = arr;
        
            numBits = numel(inputArray);
            padding = mod(4 - mod(numBits, 4), 4);
        
            % Pad data
            paddedData = [inputArray, zeros(1, padding)];
        
            % Reshape the padded data 
            numWords = numel(paddedData) / 4;
            dataMatrix = reshape(paddedData, 4, numWords).';
        
            hammingMatrix = [
                1 0 0 0 1 1 1;
                0 1 0 0 0 1 1;
                0 0 1 0 1 0 1;
                0 0 0 1 1 1 0;
            ];
        
            % Initialize the result matrix
            resultMatrix = zeros(size(dataMatrix, 1), size(hammingMatrix, 2));
        
            % Perform (7,4) Hamming encoding bit-wise
            for i = 1:size(dataMatrix, 1)
                resultMatrix(i, :) = mod(dataMatrix(i, :) * hammingMatrix, 2);
            end
        
            % Reshape the result matrix
            Hamming = reshape(resultMatrix.', 1, []);
            %disp('Original Data:');
            %disp(dataMatrix);
            %disp('Encoded Data (Hamming Code):');
            %disp(encodedData);
                        
        end
        
        function resultArray  = BCH127_64_1(app, arr)
            % Specify BCH parameters
            %M=7;
            n = 127; % Codeword length
            k = 64;  % Message length
            t = 32;  % Error-correction capability
            
            % Generate an array of binary digits (0s and 1s)
            inputArray = arr;
            padding = mod(k - mod(length(inputArray), k), k);
            inputArray = [inputArray, zeros(1, padding)];

            numGroups = length(inputArray) / k;
            inputMatrix = reshape(inputArray, k, numGroups).';

            resultArray = zeros(1, n * numGroups);

            for i = 1:numGroups
            inputGF = gf(inputMatrix(i, :), 1);
            app.BCH127_64 = bchenc(inputGF, n, k);
            resultArray((i - 1) * n + 1 : i * n) = app.BCH127_64.x;
            end

            app.BCH127_64 = resultArray;


            % Introduce errors into the encoded array
            % errorLocations = randperm(n, t);
            % encodedArrayWithError = encodedArray;
            % encodedArrayWithError(errorLocations) = mod(encodedArrayWithError(errorLocations) + 1, 2);
            % 
            % Decode the array using BCH
            % decodedArray = bchdec(encodedArrayWithError, n, k);
            % 
            % Display results
            % disp('Original Array:');
            % disp(inputArray);
            % disp('Encoded Array:');
            % disp(encodedArray);
            % disp('Encoded Array with Errors:');
            % disp(encodedArrayWithError);
            % disp('Decoded Array:');
            % disp(decodedArray);                                    
        end
        
        function res_array = BCH63_51_1(app, arr)
            n = 63; % Codeword length
            k = 51;  % Message length
            t = 32;  % Error-correction capability
            
            % Generate an array of binary digits (0s and 1s)
            inputArray = arr;
            padding = mod(k - mod(length(inputArray), k), k);
            inputArray = [inputArray, zeros(1, padding)];

            numGroups = length(inputArray) / k;
            inputMatrix = reshape(inputArray, k, numGroups).';

            res_array = zeros(1, n * numGroups);

            for i = 1:numGroups
            inputGF = gf(inputMatrix(i, :), 1);
            app.BCH63_51 = bchenc(inputGF, n, k);
            res_array((i - 1) * n + 1 : i * n) = app.BCH63_51.x;
            end

            app.BCH63_51 = res_array;
            
            
            % Introduce errors into the encoded array
            % errorLocations = randperm(n, t);
            % encodedArrayWithError = encodedArray;
            % encodedArrayWithError(errorLocations) = mod(encodedArrayWithError(errorLocations) + 1, 2);
            % 
            % Decode the array using BCH
            % decodedArray = bchdec(encodedArrayWithError, n, k);
            % 
            % Display results
            % disp('Original Array:');
            % disp(inputArray);
            % disp('Encoded Array:');
            % disp(encodedArray);
            % disp('Encoded Array with Errors:');
            % disp(encodedArrayWithError);
            % disp('Decoded Array:');
            % disp(decodedArray);                                    
            
        end
        
        function FSKSignal1 = FSK_16_ham(app, arr,m)
            % Define FSK modulation parameters

            hammingCode=arr;
            if m==16
                r=4;
            else 
                r=6;
            end    
            if mod(length(arr),log2(m))~=0
                app.neededBits=log2(m)-mod(length(arr),log2(m));
                arr=[arr,zeros(1,app.neededBits)];
            end      
            arr = reshape(arr, r, []);
            fsk_arg = bit2int(arr, r);
            FSKSignal1=fskmod(fsk_arg,m,16,8,2000);
            
        end
        
        
        function QAMSignal1 = QAM_16_Ham(app,hammingEncodedBits,M)
            % Define QAM parameters
            % QAM modulation order
            bitSequence=hammingEncodedBits;
            % Map the Hamming encoded bits to symbols
            if M==16
                numBitsPerSymbol=4;
            else
                numBitsPerSymbol=6;
            end
            numSymbols = floor(length(bitSequence) / numBitsPerSymbol);
            bitSequenceReshaped = reshape(bitSequence(1:numSymbols * numBitsPerSymbol), numBitsPerSymbol, numSymbols).';

            % Check if there are leftover bits
            app.remainingBits = bitSequence(numSymbols * numBitsPerSymbol + 1:end);
            
            if ~isempty(app.remainingBits)
            app.padding = numBitsPerSymbol - length(app.remainingBits);
            app.remainingBits = [app.remainingBits, zeros(1, app.padding)];
            bitSequenceReshaped = [bitSequenceReshaped; app.remainingBits];
            end

            mappedSymbols = qammod(bitSequenceReshaped(:), M,'InputType','bit',PlotConstellation=true);
        
            % Output the modulated QAM signal
            QAMSignal1 = mappedSymbols;            
        end
        
        
        function bits = audioToBinary(app,audioData,bitsPerSample)
            audioData = audioData / max(abs(audioData));
    
            normalizedData = (audioData - min(audioData)) / (max(audioData) - min(audioData));

            % Convert the normalized data to binary bits
            quantizedData = round(normalizedData * (2^bitsPerSample - 1));
            bits = de2bi(quantizedData, bitsPerSample, 'left-msb');
        
            % Reshape the bits to a row vector
            bits = bits(:)';           
        end
        
        function FSKSignal2 = fsk_dem_ham(app,noisySignal,m)
            % Define FSK demodulation parameters
            if m == 16
                r = 4;
                frequencies = linspace(1e3, 2e3, 16);
            else
                r = 6;
                frequencies = linspace(1e3, 2e3, 64);
            end
        
            % Demodulate using FSK
            fsk_demod = fskdemod(noisySignal, m, 16, 8, 2000);
        
            % Convert demodulated symbols to bits
            demodulatedBits = int2bit(fsk_demod, log2(m));
        
            % Remove padded zeros
            demodulatedBits = reshape(demodulatedBits.', 1, []);
            if app.neededBits > 0
                demodulatedBits = demodulatedBits(1:end - app.neededBits);
            end
            FSKSignal2=demodulatedBits;
        end
        
        function qam2 = qamdemodw(app,QAMSignal1,M)
            % Demodulate the QAM signal
            demodulatedBits = qamdemod(QAMSignal1, M, 'OutputType', 'bit');
            
            % Reshape the demodulated bits to match the original bit sequence
            numBitsPerSymbol = log2(M);
            demodulatedBits = reshape(demodulatedBits, [], numBitsPerSymbol).';
            
            % Remove any padded zeros
            if ~isempty(app.remainingBits)
                demodulatedBits = demodulatedBits(:)';
                demodulatedBits = demodulatedBits(1:end - app.padding);
            end
            
            % Convert to binary array
            demodulatedArray = demodulatedBits(:)';
            qam2=demodulatedArray;
        end
        
        function ham_decode = ham_decode_1(app,arr)
            % Reshape the Hamming code into a matrix
            hammingCode=arr;  
            numBits = length(hammingCode);
            numHammingBits = 7;
            numDataBits = 4;
            numWords = numBits / numHammingBits;
            hammingMatrix = reshape(hammingCode, numHammingBits, numWords).';
        
            % Create the Hamming decoding matrix
            decodingMatrix = [
                1 0 0 1 0 1 1;
                0 1 0 1 1 0 1;
                0 0 1 0 1 1 1;
            ];
        
            % Initialize the result matrix
            resultMatrix = zeros(size(hammingMatrix, 1), size(decodingMatrix, 2));
        
            % Perform Hamming decoding bit-wise
            for i = 1:size(hammingMatrix, 1)
                resultMatrix(i, :) = mod(hammingMatrix(i, :) * decodingMatrix, 2);
            end
        
            % Reshape the result matrix and remove padding
            decodedBits = reshape(resultMatrix.', 1, []);
            padding1 = mod(4 - mod(length(decodedBits), 4), 4);
            ham_decode = decodedBits(1:end - padding1);
          end
        
          function bchdec = BCHDecodeMultipleGroups(app,BCHCode,n,k)
            % Specify BCH parameters
            t = 32;  % Error-correction capability
        
            % Determine the number of words (groups)
            numWords = size(BCHCode, 1);
        
            % Create a Galois field array
            gfArray = gf(BCHCode, 1);
        
            % Initialize the decoded bits
            decodedBits = [];
        
            % Decode each word (group) separately
            for i = 1:numWords
                % Extract a word (group) from the BCH code
                currentWord = gfArray(i, :);
        
                % Decode the current word
                BCHDecoded = bchdec(currentWord, n, k, t);
        
                % Convert the Galois field array back to bits and append to the result
                bchdec = [decodedBits, BCHDecoded.x];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateBitsButton
        function GenerateBitsButtonPushed(app, event)
            if app.RandomBitsButton.Value
                numBits = 126;
                app.randomBits = generateRandomBits(app, numBits);

            elseif app.TextFileButton.Value
                [file,path] = uigetfile('assignment.txt');
                if isequal(file,0) == false
                   app.randomBits = textToBits(app, fullfile(path,file));
                   numBits = length(app.randomBits);
                end
            elseif app.ImageFileButton.Value
                [file,path] = uigetfile({'*.jpeg';'*.png';'*.jpg'});
                if isequal(file,0) == false
                   app.randomBits = imageToBits(app, fullfile(path,file));
                   numBits = length(app.randomBits);
                end
            elseif app.VoiceButton.Value && app.GenerateBitsButton.Text == "Generate Bits"
                app.GenerateBitsButton.Text = "Recording";
                Fs = 44100; 
                nBits = 16; 
                nChannels = 1; 
                ID = -1; % default audio input device 
                app.recObj = audiorecorder(Fs, nBits, nChannels, ID); %create audio object
            
                % Start recording for 3 seconds
                record(app.recObj, 3);
                disp('Recording...');
            
                % Wait for the recording to finish
                pause(3);
            
                % Stop recording
                stop(app.recObj);
                app.GenerateBitsButton.Text = "Generate";
                
            
                % Get recorded audio data
                audioData = getaudiodata(app.recObj);
            
                % Convert audio data to binary bits (example)
                bitsPerSample = 8;
                app.randomBits = app.audioToBinary(audioData,bitsPerSample);
                numBits=length(app.randomBits);


            % elseif app.VoiceButton.Value && app.GenerateBitsButton.Text == "Stop Recording"
            %     app.GenerateBitsButton.Text = "Generate";
            %     stop(app.recObj) % Stop audio recording   
            %     b=getaudiodata(app.recObj);
            %     app.randomBits = audioToBits(app, b);
            %     numBits = length(app.randomBits);
            end

            app.TextArea.Value=num2str(app.randomBits);

            numBits=length(app.randomBits);
            time = 0:numBits-1;
            plot(app.UIAxes,time, app.randomBits);
            %xlim(app.UIAxes, [0 numBits-1]);
        end

        % Button pushed function: GenerateCodeButton
        function GenerateCodeButtonPushed(app, event)
            if app.HammingCode74Button.Value
                app.TextArea_2.Value=num2str(app.Hamming1(app.randomBits));
                app.mat3=num2str(app.Hamming1(app.randomBits));
                app.b2=1;
            elseif app.BCH12764codeButton.Value
                app.TextArea_2.Value=num2str    (app.BCH127_64_1(app.randomBits));
                app.mat3=num2str    (app.BCH127_64_1(app.randomBits));
                app.b2=2;
            elseif app.BCH6351codeButton.Value
                app.TextArea_2.Value=num2str(app.BCH63_51_1(app.randomBits));
                app.mat3=num2str(app.BCH63_51_1(app.randomBits));
                app.b2=3;
            end    
        end

        % Button pushed function: ModulateButton
        function ModulateButtonPushed(app, event)
            if app.FSK16Button.Value && app.b2==1
                app.TextArea_2.Value=num2str(app.FSK_16_ham(app.Hamming1(app.randomBits),16));
                app.mat1=mat2str(app.FSK_16_ham(app.Hamming1(app.randomBits),16));
                app.b3=1;
            elseif app.FSK16Button.Value && app.b2==2
                app.TextArea_2.Value=num2str(app.FSK_16_ham(app.BCH127_64_1(app.randomBits),16));
                app.mat1=num2str(app.FSK_16_ham(app.BCH127_64_1(app.randomBits),16));
                app.b3=1;
            elseif app.FSK16Button.Value && app.b2==3
                app.TextArea_2.Value=num2str(app.FSK_16_ham(app.BCH63_51_1(app.randomBits),16)); 
                app.mat1=num2str(app.FSK_16_ham(app.BCH63_51_1(app.randomBits),16));
                app.b3=1;
            elseif app.QAM16Button.Value && app.b2==1
                app.TextArea_2.Value=mat2str(app.QAM_16_Ham(app.Hamming1(app.randomBits),16));
                app.mat1=mat2str(app.QAM_16_Ham(app.Hamming1(app.randomBits),16));
                app.b3=2;
            elseif app.QAM64Button.Value && app.b2==1
                app.TextArea_2.Value=mat2str(app.QAM_16_Ham(app.Hamming1(app.randomBits),64));
                app.mat1=mat2str(app.QAM_16_Ham(app.Hamming1(app.randomBits),64));
                app.b3=3;
            elseif  app.QAM16Button.Value && app.b2==2
                app.TextArea_2.Value=mat2str(app.QAM_16_Ham(app.BCH127_64_1(app.randomBits),16));
                app.mat1=mat2str(app.QAM_16_Ham(app.BCH127_64_1(app.randomBits),16));
                app.b3=2;
            elseif  app.QAM64Button.Value && app.b2==2
                app.TextArea_2.Value=mat2str(app.QAM_16_Ham(app.BCH127_64_1(app.randomBits),64));
                app.mat1=mat2str(app.QAM_16_Ham(app.BCH127_64_1(app.randomBits),64));
                app.b3=3;
            elseif  app.QAM16Button.Value && app.b2==3
                app.TextArea_2.Value=mat2str(app.QAM_16_Ham(app.BCH63_51_1(app.randomBits),16));
                app.mat1=mat2str(app.QAM_16_Ham(app.BCH63_51_1(app.randomBits),16));
                app.b3=2;
            elseif  app.QAM64Button.Value && app.b2==3
                app.TextArea_2.Value=mat2str(app.QAM_16_Ham(app.BCH63_51_1(app.randomBits),64));
                app.mat1=mat2str(app.QAM_16_Ham(app.BCH63_51_1(app.randomBits),64));
                app.b3=3;
            elseif app.FSK64Button.Value && app.b2==1
                app.TextArea_2.Value=num2str(app.FSK_16_ham(app.Hamming1(app.randomBits),64));
                app.mat1=num2str(app.FSK_16_ham(app.Hamming1(app.randomBits),64));
                app.b3=4;
            elseif app.FSK64Button.Value && app.b2==2
                app.TextArea_2.Value=num2str(app.FSK_16_ham(app.BCH127_64_1(app.randomBits),64));
                app.mat1=num2str(app.FSK_16_ham(app.BCH127_64_1(app.randomBits),64));
                app.b3=4;
            elseif app.FSK64Button.Value && app.b2==3
                app.TextArea_2.Value=num2str(app.FSK_16_ham(app.BCH63_51_1(app.randomBits),64));    
                app.mat1=num2str(app.FSK_16_ham(app.BCH63_51_1(app.randomBits),64));
                app.b3=4;
            end    
        end

        % Button pushed function: GeneratePSDandTimedomainButton
        function GenPsdPushed(app, event)
            fs = 2000;  % Sampling frequency
            time = 0:1/fs:(length(app.mat1)-1)/fs;
            timeDomainSignal = real(app.mat1);
            plot(app.UIAxes2, time, timeDomainSignal);

            [psd, freq] = pwelch(double(app.mat1),[],[],[],[],[], fs);
             plot(app.UIAxes3, freq, 10*log10(psd));
        end

        % Value changed function: NoiseSlider
        function Vluechng(app, event)
            noiseLevel = app.NoiseSlider.Value;
            complexSignal = app.mat1;
            doubleSignal = [real(complexSignal), imag(complexSignal)];
        
            % Calculate the SNR from the noise level
            %snr = -10 * log10(noiseLevel);
        
            % Add AWGN to the signal
            app.NoisySignal = awgn(doubleSignal, noiseLevel);           
        end

        % Button pushed function: DemodulateButton
        function DemodulatePushed(app, event)
            if app.b2==1 && app.b3==1
                app.TextArea_2.Value=ham_decode_1(app,app.mat3);
            elseif app.b2==2 && app.b3==1
                app.TextArea_2.Value=BCHDecodeMultipleGroups(app,app.mat3,127,64);
            elseif app.b2==3 && app.b3==1
                app.TextArea_2.Value=BCHDecodeMultipleGroups(app,app.mat3,63,51);
            elseif app.b2==1 && app.b3==4
                app.TextArea_2.Value=ham_decode_1(app,app.mat3);
            elseif app.b2==2 && app.b3==4
                app.TextArea_2.Value=BCHDecodeMultipleGroups(app,app.mat3,127,64);
            elseif app.b2==3 && app.b3==4
                app.TextArea_2.Value=BCHDecodeMultipleGroups(app,app.mat3,63,51);
            elseif app.b2==1 && app.b3==2
                app.TextArea_2.Value=ham_decode_1(app,qamdemodw(app,app.NoisySignal,16));
            elseif app.b2==1 && app.b3==3
                app.TextArea_2.Value=ham_decode_1(app,qamdemodw(app,app.NoisySignal,64));
            elseif app.b2==2 && app.b3==2
                app.TextArea_2.Value=BCHDecodeMultipleGroups(app,qamdemodw(app,app.NoisySignal,16),127,64);
            elseif app.b2==3 && app.b3==2
                app.TextArea_2.Value=BCHDecodeMultipleGroups(app,qamdemodw(app,app.NoisySignal,16),63,51);
            elseif app.b2==2 && app.b3==3
                app.TextArea_2.Value=BCHDecodeMultipleGroups(app,qamdemodw(app,app.NoisySignal,64),127,64);
            elseif app.b2==3 && app.b3==3
                app.TextArea_2.Value=BCHDecodeMultipleGroups(app,qamdemodw(app,app.NoisySignal,64),63,51);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'x')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 1.2];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Time Domain Graph')
            xlabel(app.UIAxes2, 'TIME')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'PSD')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.RandomBitsButton = uiradiobutton(app.ButtonGroup);
            app.RandomBitsButton.Text = 'Random Bits';
            app.RandomBitsButton.Value = true;
            app.TextFileButton = uiradiobutton(app.ButtonGroup);
            app.TextFileButton.Text = 'Text File';
            app.ImageFileButton = uiradiobutton(app.ButtonGroup);
            app.ImageFileButton.Text = 'Image File';
            app.VoiceButton = uiradiobutton(app.ButtonGroup);
            app.VoiceButton.Text = 'Voice';
            app.GenerateBitsButton = uibutton(app.UIFigure, 'push');
            app.GenerateBitsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBitsButtonPushed, true);
            app.GenerateBitsButton.Text = 'Generate Bits';
            app.CODESButtonGroup = uibuttongroup(app.UIFigure);
            app.CODESButtonGroup.Title = 'CODES';
            app.HammingCode74Button = uiradiobutton(app.CODESButtonGroup);
            app.HammingCode74Button.Text = 'Hamming Code(7,4)';
            app.HammingCode74Button.Value = true;
            app.BCH12764codeButton = uiradiobutton(app.CODESButtonGroup);
            app.BCH12764codeButton.Text = 'BCH (127,64) code';
            app.BCH6351codeButton = uiradiobutton(app.CODESButtonGroup);
            app.BCH6351codeButton.Text = 'BCH (63,51) code';
            app.GenerateCodeButton = uibutton(app.UIFigure, 'push');
            app.GenerateCodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCodeButtonPushed, true);
            app.GenerateCodeButton.Text = 'Generate Code';
            app.INPUTLabel = uilabel(app.UIFigure);
            app.INPUTLabel.Text = 'INPUT';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.ModulationButtonGroup = uibuttongroup(app.UIFigure);
            app.ModulationButtonGroup.Title = 'Modulation';
            app.FSK16Button = uiradiobutton(app.ModulationButtonGroup);
            app.FSK16Button.Text = 'FSK 16';
            app.FSK16Button.Value = true;
            app.QAM16Button = uiradiobutton(app.ModulationButtonGroup);
            app.QAM16Button.Text = 'QAM 16';
            app.QAM64Button = uiradiobutton(app.ModulationButtonGroup);
            app.QAM64Button.Text = 'QAM 64';
            app.FSK64Button = uiradiobutton(app.ModulationButtonGroup);
            app.FSK64Button.Text = 'FSK 64';
            app.ModulateButton = uibutton(app.UIFigure, 'push');
            app.ModulateButton.ButtonPushedFcn = createCallbackFcn(app, @ModulateButtonPushed, true);
            app.ModulateButton.Text = 'Modulate';
            app.OUTPUTLabel = uilabel(app.UIFigure);
            app.OUTPUTLabel.Text = 'OUTPUT';
            app.GeneratePSDandTimedomainButton = uibutton(app.UIFigure, 'push');
            app.GeneratePSDandTimedomainButton.ButtonPushedFcn = createCallbackFcn(app, @GenPsdPushed, true);
            app.GeneratePSDandTimedomainButton.Text = 'Generate PSD and Time domain';
            app.NoiseSliderLabel = uilabel(app.UIFigure);
            app.NoiseSliderLabel.Text = 'Noise';
            app.NoiseSlider = uislider(app.UIFigure);
            app.NoiseSlider.Limits = [1 20];
            app.NoiseSlider.ValueChangedFcn = createCallbackFcn(app, @Vluechng, true);
            app.NoiseSlider.Value = 1;
            app.DemodulateButton = uibutton(app.UIFigure, 'push');
            app.DemodulateButton.ButtonPushedFcn = createCallbackFcn(app, @DemodulatePushed, true);
            app.DemodulateButton.Text = 'Demodulate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end